classdef htpInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        QuitMenu                       matlab.ui.container.Menu
        AboutHTPMenu                   matlab.ui.container.Menu
        UtilityMenu                    matlab.ui.container.Menu
        EditStageOptionsXMLMenu        matlab.ui.container.Menu
        EditPresetOptionsXMLMenu       matlab.ui.container.Menu
        EditElectrodeOptionsXMLMenu    matlab.ui.container.Menu
        OpenscriptfolderMenu           matlab.ui.container.Menu
        ViewdatamethodsMenu            matlab.ui.container.Menu
        ViewpreprocessingmethodsMenu   matlab.ui.container.Menu
        ExportsubjectstoworkspaceMenu  matlab.ui.container.Menu
        CodeMenu                       matlab.ui.container.Menu
        ReportingMenu                  matlab.ui.container.Menu
        CSVfileoutputMenu              matlab.ui.container.Menu
        EEGHighThroughputPipelineHTPLabel  matlab.ui.control.Label
        ProgramOutputLabel             matlab.ui.control.Label
        StatusTextArea                 matlab.ui.control.TextArea
        FIlenameEditFieldLabel         matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        NetTypeDropDownLabel           matlab.ui.control.Label
        NetTypeDropDown                matlab.ui.control.DropDown
        OpenButton                     matlab.ui.control.Button
        Step1Label                     matlab.ui.control.Label
        StudyFolderEditFieldLabel      matlab.ui.control.Label
        StudyFolderEditField           matlab.ui.control.EditField
        SelectButton                   matlab.ui.control.Button
        LoadinEEGLABButton             matlab.ui.control.Button
        DropDownLabel                  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        LoadStagesButton               matlab.ui.control.Button
        OutputFolderEditFieldLabel     matlab.ui.control.Label
        OutputFolderEditField          matlab.ui.control.EditField
        ViewFolderButton               matlab.ui.control.Button
        SetupPresetsandFileLocationsLabel  matlab.ui.control.Label
        AnalysisModeSwitchLabel        matlab.ui.control.Label
        AnalysisModeSwitch             matlab.ui.control.Switch
        LastButton                     matlab.ui.control.Button
        PresetDropDownLabel            matlab.ui.control.Label
        PresetDropDown                 matlab.ui.control.DropDown
        TabGroup                       matlab.ui.container.TabGroup
        PreProcessingTab               matlab.ui.container.Tab
        Stage1Button                   matlab.ui.control.Button
        Stage2Button                   matlab.ui.control.Button
        Stage3Button                   matlab.ui.control.Button
        Stage4Button                   matlab.ui.control.Button
        Stage5Button                   matlab.ui.control.Button
        SaveasListedPresetButton       matlab.ui.control.Button
        AnalysisModuleTab              matlab.ui.container.Tab
        LoadTriggersButton             matlab.ui.control.Button
        SelectEpochEventDropDownLabel  matlab.ui.control.Label
        SelectEpochEventDropDown       matlab.ui.control.DropDown
        PowerBandLabel                 matlab.ui.control.Label
        PowerBandDropDown              matlab.ui.control.DropDown
        PowerBandTable                 matlab.ui.control.Table
        pow_mtButton                   matlab.ui.control.Button
        powTable                       matlab.ui.control.Table
        DatasetInfoLabel               matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        ResultDropDownLabel            matlab.ui.control.Label
        ResultDropDown                 matlab.ui.control.DropDown
        ComponentPanelpostcompssetsonly  matlab.ui.container.Panel
        ComponentViewButton            matlab.ui.control.Button
        RemoveCheckBox                 matlab.ui.control.CheckBox
        EditCheckBox                   matlab.ui.control.CheckBox
        CompstoRemoveEditFieldLabel    matlab.ui.control.Label
        CompstoRemoveEditField         matlab.ui.control.EditField
        ViewOriginalButton             matlab.ui.control.Button
        ViewRemovedButton              matlab.ui.control.Button
        ViewButton                     matlab.ui.control.Button
        SaveCSVButton                  matlab.ui.control.Button
        UserInitialsEditFieldLabel     matlab.ui.control.Label
        UserInitialsEditField          matlab.ui.control.EditField
        StudyNameEditFieldLabel        matlab.ui.control.Label
        StudyNameEditField             matlab.ui.control.EditField
        PrepareFilesButton             matlab.ui.control.Button
        subTable                       matlab.ui.control.Table
        SubjectDetailPanel             matlab.ui.container.Panel
        basenameLabel                  matlab.ui.control.Label
        SDetailsListBoxLabel           matlab.ui.control.Label
        SDetailsListBox                matlab.ui.control.ListBox
        SOutputTextAreaLabel           matlab.ui.control.Label
        SOutputTextArea                matlab.ui.control.TextArea
        AnalysisModeButton             matlab.ui.control.Button
        AnalysisTree                   matlab.ui.container.Tree
        TimeFrequencyNode              matlab.ui.container.TreeNode
        LogScaleNode                   matlab.ui.container.TreeNode
        LinearScaleNode                matlab.ui.container.TreeNode
        Node4                          matlab.ui.container.TreeNode
        ActivateNodeButton             matlab.ui.control.Button
        ResetInterfaceButton           matlab.ui.control.Button
        EEGLABButton                   matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        TFPlotButton                   matlab.ui.control.Button
        AnalysisModuleLabel            matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        Save2WorkspaceButton           matlab.ui.control.Button
        ReFilterButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        fileExtFilter; % Description
        guihtpcfg; % Description
        availableDatasets; % Description
        stageselect; % Description
        edit_removeComps; % Description
        orig_removeComps; % Description
        saveSettingFile; % Description
        localSettings; % Description
        loadedSettings; % Description
        DialogApp; % Description
        optNames; % Description
        runInstance; % to start fresh on each run
        activeSelection; % Description
        dialog_EventEpochs; % Description
        htpAM; % Description
    end
    
    properties (Access = public)
        configFileName; % Description
        htpPC; % Description
        htpUtilFun; % Description
        OptionLabel                 matlab.ui.control.Label
        OptionDropDown              matlab.ui.control.DropDown
        
    end
    
    methods (Access = private)
        
        function disableFileOpen(app, toggle)
            switch toggle
                case 'on'
                    enableToggle = 1;
                    enableColor = [0 1 0];
                    
                case 'off'
                    enableToggle = 0;
                    enableColor = [1 0 0];
            end
            
            green = [0 1 0];
            red = [1 0 0];
            blue = [0.3 0.75 0.93];
            
            app.FilenameEditField.Enable = enableToggle;
            app.StudyFolderEditField.Enable = enableToggle;
            app.OpenButton.Enable = enableToggle;
            app.SelectButton.Enable = enableToggle;


            app.Stage1Button.Enable = enableToggle;
            app.Stage2Button.Enable = enableToggle;
            
            app.PrepareFilesButton.Enable = ~enableToggle;
            app.PrepareFilesButton.BackgroundColor = red;
            app.LoadStagesButton.Enable = ~enableToggle;
            app.LoadStagesButton.BackgroundColor = red;
            
            if      app.htpPC.htpcfg.completed.NetTypeDropDown == 1  && ...
                    app.htpPC.htpcfg.completed.FilenameEditField == 1 && ...
                    app.htpPC.htpcfg.completed.OutputFolderEditField == 1
                
                app.PrepareFilesButton.BackgroundColor = green;
                app.PrepareFilesButton.Enable = enableToggle;
                app.LoadStagesButton.Enable = enableToggle;
                app.LoadStagesButton.BackgroundColor = green;
                
                
            end
            
            if  app.htpPC.htpcfg.completed.PrepareStageButton == 1
                app.PrepareFilesButton.Text = 'Reset: Recopy Original File and Start Over';
                app.PrepareFilesButton.BackgroundColor = blue;
                app.PrepareFilesButton.Enable = enableToggle;
                
            end
            
        end
        
        function setOptions_NetTypesDropDown(app, netPairs)
            
            instructStr = 'Please select EEG net type:';
            
            app.NetTypeDropDown.Items = {instructStr netPairs{:,1}};
            app.NetTypeDropDown.ItemsData = {'N/A' netPairs{:,2}};
            app.NetTypeDropDown.Value = app.NetTypeDropDown.ItemsData{1};
            
        end
        
        function results = updateStatusText(app, str, varargin)
            
            if nargin > 2
                msgtype = varargin{1};
            else
                msgtype = 'msg';
            end
            
            
            switch msgtype
                case 'init'
                    prefix = '(Start)';
                    str =  sprintf('Data Reset and Program Initialized\n');
                case 'msg'
                    prefix = '';
                case 'warning'
                    prefix = '(Warning)';
                case 'error'
                    prefix = '(Error)';
                case 'complete'
                    prefix = '(Complete)';
            end
            
            updatetxt = sprintf('%s %s: %s', showTimeStamp(app), prefix, str);
            app.StatusTextArea.Value = [updatetxt; app.StatusTextArea.Value];
            
            
        end
        
        function results = showTimeStamp(app)
            datetime.setDefaultFormats('default','hh:mm:ss');
            results = datetime;
        end
        
        function results = showTimeDateStamp(app)
            datetime.setDefaultFormats('default','MMddyy hh:mm');
            results = datetime;
        end
        
        function results = updateBasePath(app, pathname)
            
            app.htpPC.htpcfg.basePath = pathname;
            assignin('base', 'htp_cfg', app.htpPC.htpcfg);
            
        end
        
        function results = start_eeglab(app)
            evalin('base', 'eeglab;');
            evalin('base', 'close gcf;');
            
        end
        
        function setupOptions(app)
            
            % get option values (from XML)
            opt = app.htpPC.htpcfg.opt;
            
            app.optNames = fields(opt);
            
            no_of_options = length(app.optNames);
            opt_per_column = round(no_of_options / 2)+1;
            
            % Create OptionPlaceLabel
            ypos = app.PreProcessingTab.Position(1,4) - 85; %355;
            yoffset = 0;
            xoffset = 0;
            for i = 1 : length(app.optNames)
                
                if i >= opt_per_column
                    if i == opt_per_column
                        yoffset = 0;
                    end
                    xoffset = 265;
                    
                end
                
                app.OptionLabel(i) = uilabel(app.PreProcessingTab);
                app.OptionLabel(i).Position = [15+xoffset ypos-yoffset 175 22];
                app.OptionLabel(i).HorizontalAlignment = 'left';
                app.OptionLabel(i).Text = app.optNames{i};
                
                % Create DropDown
                app.OptionDropDown(i) = uidropdown(app.PreProcessingTab);
                app.OptionDropDown(i).Position = [155+xoffset ypos-yoffset 100 22];
                app.OptionDropDown(i).Items = opt.(app.optNames{i});
                
                yoffset = yoffset + 30;
                
            end
            
            %
            %             for i = 1 : length( app.TabGroup(1).Children(1).Children )
            %                 app.TabGroup(1).Children(1).Children(i).delete;
            %
            %             end
            %
            %             for i = 1 : length(app.optNames)
            %
            %                 app.OptionDropDown(i).delete;
            %             end
            
            optSel = [];
            app.SaveasListedPresetButton.Position(1,2) = 5;
            
        end
        
        function results = toggleOptions( app, toggle)
            
            switch toggle
                
                case 'on'
                    enableToggle = 1;
                    
                case 'off'
                    enableToggle = 0;
                    
            end
            
            
            app.LowCutoffFilterDropDown.Enable = enableToggle;
            app.HighCutoffDropDown.Enable = enableToggle;
            app.NotchDropDown.Enable = enableToggle;
            app.TargetSampleRateDropDown.Enable = enableToggle;
            app.EpochLengthDropDown.Enable = enableToggle;
            app.CleanModeDropDown.Enable = enableToggle;
            app.PCASettingsDropDown.Enable = enableToggle;
            app.BrainCoOnlyDropDown.Enable = enableToggle;
            app.PlotsDropDown.Enable = enableToggle;
            app.PrepareFilesButton.Enable = enableToggle;
            app.LoadStagesButton.Enable = enableToggle;
            app.DropDown.Enable = enableToggle;
            app.ComponentViewButton.Enable = enableToggle;
            
            app.LoadinEEGLABButton.Enable = enableToggle;
            
        end
        
        
        function results = getOptionValues(app)
            
            opt = app.htpPC.htpcfg.opt;
            fieldnames = fields(opt);
            
            for i = 1 : length( app.OptionDropDown )
                optnow{i} = app.OptionDropDown(i).Value;
                optIdx(i) = find(strcmp(optnow{i}, opt.(fieldnames{i})));
            end
            
            app.htpPC.setOptNow( optIdx );
            
            %app.htpPC.msgout( 'Selected Options;', 'step_msg' );
            %             for i = 1 : length( fieldnames )
            %
            %                 str = sprintf( '%s: %s (%d)', fieldnames{i},  app.htpPC.htpcfg.optnow.(fieldnames{i}){1}, optIdx(i));
            %
            %                 app.htpPC.msgout( str, 'step_msg' );
            %
            %             end
            
            try
                app.htpAM.setOptNow( optIdx );
                
            catch
                
            end
            
            
            results = optIdx;
            
        end
        
        function results = getSubject(app, stage)
            
            % selecting objects by CSV
            app.htpPC.getStageCSV(stage, app.htpPC.htpcfg.basePath);
            
            csvfile = app.htpPC.htpcfg.csvfile;
            matfile = app.htpPC.htpcfg.matfile;
            pathdb =  app.htpPC.htpcfg.pathdb;
            
            % Load subject objects
            load(matfile, 'sub');
            arrayfun(@(sub) sub.setCsv( csvfile ), sub, 'UniformOutput',false );
            arrayfun(@(sub) sub.setMat( matfile ), sub, 'UniformOutput',false );
            arrayfun(@(sub) sub.updatePaths( app.htpPC.htpcfg.basePath ), sub, 'UniformOutput',false );
            results = sub;
            
            
        end
        
        function results = setOptions_PCACompsDropDown(app)
            % can modify PCA parameters prior to ICA
            
            app.PCASettingsDropDown.Items = {'Rank Only', 'K-number', '24'};
            
        end


        function setOptions_PresetDropDown(app)
            
            % Preset box populated from xml file
            
            items = {app.htpPC.xml_presets{:,1}};
            app.PresetDropDown.Items = items;
            itemIdx = strcmp(app.PresetDropDown.Value, items);
            
            % tempset = app.htpPC.xml_presets(itemIdx).Setting;
            
            %   app.PresetDropDown_2.Items = tempset;


        end
        
        
        function results = setNet(app, netvalue)
            
            value = netvalue;
            
            app.htpPC.setElecNow( value );
            
            try
                if ~strcmp(value, 'N/A')
                    app.fileExtFilter = app.htpPC.htpcfg.chanNow.net_filter;
                    buttonHandler(app, 'netNow');
                    app.htpPC.htpcfg.completed.NetTypeDropDown = 1;
                else
                    buttonHandler(app, 'netOff');
                    app.htpPC.htpcfg.completed.NetTypeDropDown = 0;
                end
                results = 1;
            catch
                
                app.htpPC.msgout('Error in Channel Assignment (NetNow).', 'step_error');
                
            end
        end
        
        function results = setOptions_ResultDropDown(app)
            
            app.ResultDropDown.Items = ...
                { ...
                'Abs + Rel Pow Spectrum (1)' ...
                };
            
            app.ResultDropDown.ItemsData = [1];
            
        end
        
        function results = writeMsg(app)
            
            app.updateStatusText('Heard Message');
            results = 1;
        end
        
        function results = eventHandler(app, src, e)
            
            eventname = e.EventName;
            msg = src.msg;
            
            switch eventname
                
                case 'step_error'
                    
                    app.updateStatusText( msg, 'error' );
                    
                    if src.htpcfg.noeeglabpath == 0
                        uialert(app.UIFigure,...
                            'Critical Error: Please Add EEGLAB Directory to MATLAB and restart HTP.',...
                            'Missing Path', 'CloseFcn', @(src,event)mycallback(app,src,event));
                    end
                    
                    
                case 'step_warning'
                    
                    app.updateStatusText( msg, 'warning' );
                    
                case 'step_complete'
                    
                    app.updateStatusText( msg, 'complete' );
                    
                case 'step_msg'
                    
                    app.updateStatusText( msg );
                    
            end
            
            
            results = 1;
            
        end
        
        
        function results = btncontrol(app, uigrp, toggle)
            
            toggleSwitch = toggle;
            all_s1 = {app.LowCutoffFilterDropDown, app.HighCutoffDropDown, app.TargetSampleRateDropDown, app.NotchDropDown, ...
                app.OriginalSampleRateEditField, app.ReReferenceMethodDropDown, app.OriginalFilesEditField, app.AssignChannelEditField, ...
                app.Stage1Button, app.OriginalFilesEditField, app.AssignChannelEditField};
            
            all_s2 = {app.BadChansEditField,app.InterpolationMethodDropDown,app.ContinuousCleaningLabel, ...
                app.CleanModeDropDown,app.EpochLengthDropDown,app.EpochLimitsmsEditField, ...
                app.toEditField,app.SelectEpochEventDropDown,app.RelabelEventEditField};
            
            all_s3 = {app.PCASettingsDropDown, app.ParallelPoolCheckBox};
            all_s4 = {app.AutoSelectBrainCompsSwitch};
            
            run_s = {app.Stage1Button, app.Stage2Button,app.Stage3Button,app.Stage4Button};
            
            switch uigrp
                
                case 'all_s1'
                    uicontrolArr = all_s1;
                case 'all_s2'
                    uicontrolArr = all_s2;
                case 'all_s3'
                    uicontrolArr = all_s3;
                case 'all_s4'
                    uicontrolArr = all_s4;
                case 'all_s'
                    uicontrolArr = horzcat(all_s1, all_s2, all_s3, all_s4);
                    
                case 'run_s1', uicontrolArr = run_s{1};
                case 'run_s2', uicontrolArr = run_s{2};
                case 'run_s3', uicontrolArr = run_s{3};
                case 'run_s4', uicontrolArr = run_s{4};
                    
            end


            for i = 1 : length(uicontrolArr)
                
                uicontrolArr{i}.Enable = toggleSwitch;
                
            end
            
        end
        
        function results = startupHandler(app, state)
            
            % never run
            % subsequent runs
            % reset]
            
            switch state
                
                
                case 'never_run'
                    
                    agp = @(x) addpath(genpath(x));
                    agp('classes');
                    basedir = fileparts(which('htpInterface'));
                    cd(basedir);
                    
                    app.htpPC = htpPreprocessMaster;
                    
                    app.htpPC.checkEegLab;
                    app.htpPC.init_path;
                    app.htpPC.init_environment;
                    
                    % listener for logging events
                    addlistener( app.htpPC, 'step_complete', @(src, e) app.eventHandler(src, e));
                    addlistener( app.htpPC, 'step_error', @(src, e) app.eventHandler(src, e));
                    addlistener( app.htpPC, 'step_warning', @(src, e) app.eventHandler(src, e));
                    addlistener( app.htpPC, 'step_msg', @(src, e) app.eventHandler(src, e));
                    
                    app.updateStatusText('init','init');
                    
                    
                    app.htpPC.generateSaveFileName;
                    setAnalysisModeSwitch(app, 'Group');
                    %app.htpPC.setAnalysisMode( app.AnalysisModeSwitch.Value );
                    
                    app.localSettings = struct();
                    app.setupOptions;
                    %app.buttonHandler('init');
                    
                    % setup items in options
                    setOptions_PresetDropDown( app );
                    setOptions_ResultDropDown( app );
                    
                    app.ActivateNodeButton.Enable = 0;
                    app.UserInitialsEditField.Enable = 0;
                    app.StudyNameEditField.Enable = 0;


                case 'subsequent_run'
                    
            end
            
            
        end
        
        function detailHandler(app, dh)
            app.SDetailsListBox.Items = {};
            app.SDetailsListBox.Items = dh.fn;
            
            s = app.htpPC.sub(app.activeSelection);
            
            str{1} = app.htpPC.detailHandler(app.activeSelection, 'get', 'subj_basename');
            str{2} = app.htpPC.detailHandler(app.activeSelection, 'get', 'subj_subfolder');
            str{3} = strcat('Exclude: ', string(s.get_exclude_switch));
            str{4} = strcat('Notes: ');
            
            
            parent = app.basenameLabel.Parent;
            position =  app.basenameLabel.Position;
            offset = 20;
            delete(app.basenameLabel);
            %app.basenameLabel(1) =  app.basenameLabel;
            % app.basenameLabel(1).Text = str{1};
            
            for i = 1 : length(str)
                app.basenameLabel(i) = matlab.ui.control.Label;
            end
            
            for i = 1 : length(str)
                
                app.basenameLabel(i) =  matlab.ui.control.Label;
                app.basenameLabel(i).Parent = parent;
                app.basenameLabel(i).Position = position;
                if i > 1
                    app.basenameLabel(i).Position(2) = app.basenameLabel(i-1).Position(2) - offset;
                end
                app.basenameLabel(i).Text = str{i};
            end
            
            
            %  app.basenameLabel.Text = app.htpPC.detailHandler(app.activeSelection, 'get', 'subj_basename');


        end
        
        function set_activeSelection(app, idx)
            app.activeSelection = idx;
        end
        function idx = get_activeSelection( app )
            idx = app.activeSelection;
        end
        
        function results = loadSubTable(app, stage)
            
            app.htpPC.getStageCSV( stage, app.htpPC.htpcfg.basePath);
            %app.htpPC.populateDatatable( stage );
            app.htpPC.csvDataTable( stage, 'load');
            
            column_names = app.htpPC.dataTable.Properties.VariableNames;
            data = table2cell(app.htpPC.dataTable);


            %app.htpPC.include_all_subjects('yes');
            %tbl = app.htpPC.getDataColumn({'exclude_switch', 'subj_basename', 'subj_id', 'subj_subfolder', 'net_nbchan_orig'});
            %tmpvarnames = tbl.Properties.VariableNames;
            
            %tmpvarnames{ strcmp('exclude_switch', tmpvarnames) } = 'Exclude';
            
            app.subTable.ColumnName = column_names;
            app.subTable.Data = data;
            app.subTable.ColumnEditable = true;
            app.subTable.ColumnWidth = 'auto';
            figure(app.UIFigure);
            
            if strcmp('postcomps', stage)
                app.AnalysisModeButton.Enable = 1;
            else
                app.AnalysisModeButton.Enable = 1;
            end
            
            app.toggleDetails( 'on');
            
            
        end
        
        function results = setAnalysisModeSwitch(app, value)
            
            app.AnalysisModeSwitch.Value = value;
            
            app.htpPC.setAnalysisMode( value );
            
            analysisModeSetup(app, value);
            
        end
        
        function update_sub_htpcfg(app)
            if length(app.htpPC.sub) >= 1
                for i = 1 : length(app.htpPC.sub)
                    app.htpPC.sub(i).htpcfg = app.htpPC.htpcfg;
                end
                app.htpPC.msgout(sprintf('%d subjects htpcfg updated', length(app.htpPC.sub)));
            else
                app.htpPC.msgout('No subjects to update.');
                
            end
        end
        
        function prepareAnalysisMenus(app)
            app.PowerBandDropDown.Items = app.htpPC.getPowerBandList;
        end
        
        function toggleDetails(app, toggle)
            
            switch toggle
                case 'off'
                    t = 0;
                case 'on'
                    t = 1;
            end
            
            sdetails = {app.basenameLabel, app.SDetailsListBox, app.SOutputTextArea};
            
            for i = 1 : length(sdetails)
                sdetails{i}.Enable = t;
            end
        end
        
    end
    
    methods (Access = public)
        
        function analysisModeSetup(app, value)
            buttonHandler(app, 'init');
            switch value
                case 'Single'
                    app.FIlenameEditFieldLabel.Text = 'Single File:';
                    app.StudyFolderEditField.Value ='';
                    app.StudyFolderEditFieldLabel.Visible = 1;
                    buttonHandler(app, 'singleMode');
                    
                case 'Group'
                    app.FIlenameEditFieldLabel.Text = 'Batch Folder:';
                    app.StudyFolderEditField.Value ='Group Mode: Output Folder same as Input File.';
                    app.OutputFolderEditField.Enable = 0;
                    app.OutputFolderEditField.Value = 'Batch Folder Mode';
                    app.StudyFolderEditFieldLabel.Visible = 0;
                    buttonHandler(app, 'groupMode');
            end
        end
        
        function buttonHandler(app, instruction)
            % main button handler for UI groups
            % define groups of buttons
            green = [0 1 0];
            red = [1 0 0];
            blue = [0.3 0.75 0.93];
            
            % net options
            ui_net = { app.NetTypeDropDown };
            
            % group mode off
            ui_groupoff = { app.StudyFolderEditField, app.SelectButton };
            
            % file/folder
            ui_fileAndFolder = { app.FilenameEditField, app.OpenButton };
            
            % result folder
            ui_resultFolder = { app.StudyFolderEditField, app.SelectButton}; % delete:  , app.LastButton_2};
            
            % stage buttons
            ui_stages = { app.Stage1Button, app.Stage2Button, app.Stage3Button, app.Stage4Button, app.Stage5Button };
            
            % save options
            ui_save = { app.LastButton }; % delete: , app.LastButton_2};
            
            % preset
            ui_preset = {app.PresetDropDown};
            
            % output folder
            ui_output = { app.OutputFolderEditField, app.ViewFolderButton };
            
            % action buttons
            ui_action = {app.PrepareFilesButton, app.LoadStagesButton, app.DropDown, app.LoadinEEGLABButton, app.SaveasListedPresetButton};
            
            if app.htpPC.is_savefile_valid == 1
                app.buttonControl( ui_save, 'on');
            else
                app.buttonControl( ui_save, 'off');
            end
            
            switch instruction
                
                case 'init'  % must select net everything else off
                    ui_arr = [ui_fileAndFolder ui_resultFolder ui_output ui_action];
                    app.buttonControl( ui_arr, 'off');
                    app.buttonControl( ui_preset, 'on');
                    app.buttonControl( ui_net, 'on');
                    
                    app.PrepareFilesButton.BackgroundColor = [1 0 0];
                    %app.PrepareFilesButton.Position = [120 743 341 24];
                    app.htpPC.htpcfg.completed.PrepareStageButton = 0;
                    
                    app.LoadStagesButton.BackgroundColor = red;
                    
                    % define net types (form: {'Display Name', 'file ext *.raw'; 'Next Name', 'Next ext'})
                    netPairs = {app.htpPC.htpcfg.chaninfo.net_displayname; app.htpPC.htpcfg.chaninfo.net_name};
                    app.setOptions_NetTypesDropDown(netPairs');
                    
                    app.htpPC.msgout(sprintf('Using saved file: %s',app.htpPC.htpcfg.saveSettingFile), 'step_complete');
                    
                    app.AnalysisModeButton.Enable = 0;
                    app.toggleDetails( 'off');
                    
                    app.SaveButton.Enable = 0;


                case 'netNow'
                    mode = app.htpPC.htpcfg.analysisMode;
                    
                    switch mode
                        case 'Single'
                            ui_arr = [ui_fileAndFolder ui_resultFolder];
                            app.FilenameEditField.Value = '';
                            app.buttonControl( ui_arr, 'on');
                        case 'Group'
                            ui_arr = ui_fileAndFolder;
                            app.FilenameEditField.Value = '';
                            app.buttonControl( ui_arr, 'on');
                    end
                    
                case 'netOff'
                    ui_arr = [ui_fileAndFolder ui_resultFolder ui_save ui_output ui_action];
                    app.buttonControl( ui_arr, 'off');
                    
                case 'resetPrepareButton'
                    
                    app.PrepareFilesButton.BackgroundColor = [1 0 0];
                    % app.PrepareFilesButton.Position = [120 743 341 24];
                    % app.PrepareFilesButton.Text = 'Prepare for Stage 1';
                    app.htpPC.htpcfg.completed.PrepareStageButton = 0;
                    
                case 'groupMode'
                    ui_arr = [ui_fileAndFolder ui_resultFolder ui_output ui_stages];
                    
                    app.buttonControl( ui_arr, 'off');
                    app.PrepareFilesButton.Text = 'Load File List';
                    
                    
                    %                     ui_arr = [ui_groupoff];
                    %                     app.buttonVisible( ui_arr, 'off' );
                    
                    if app.htpPC.htpcfg.completed.NetTypeDropDown == 1
                        app.buttonHandler( 'netNow' );
                    else
                        ui_arr = ui_fileAndFolder;
                        app.FilenameEditField.Value = 'Please select net type first.';
                        app.buttonControl( ui_arr, 'off');
                        
                    end
                case 'singleMode'
                    
                    ui_arr = [ui_groupoff ui_stages];
                    app.buttonVisible( ui_arr, 'on' );
                    app.PrepareFilesButton.Text = 'Copy Raw File';
                    
                    
                    if app.htpPC.htpcfg.completed.NetTypeDropDown == 1
                        app.buttonHandler( 'netNow' );
                    else
                        ui_arr = [ui_fileAndFolder ui_resultFolder ui_resultFolder ui_output];
                        app.FilenameEditField.Value = 'Please select net type first.';
                        app.buttonControl( ui_arr, 'off');
                        
                    end
                    
                case 'FileFolderSelected'
                    mode = app.htpPC.processing_mode;
                    
                    switch mode
                        case 'Single'
                            
                            if app.htpPC.htpcfg.completed.FilenameEditField
                            end
                            
                        case 'Group'
                            ui_arr =  [ui_action app.ViewFolderButton];
                            app.buttonControl( ui_arr, 'on');
                            
                    end
                    
                case 'LastSingleFileOrFolder'
                    
                    mode = app.htpPC.processing_mode;
                    
                    switch mode
                        case 'Single'
                            if app.htpPC.htpcfg.completed.FilenameEditField == 1 && app.htpcfg.completed.TargetFolderEditField  == 1
                                app.buttonHandler('FilesPrepared');
                            end
                            
                        case 'Group'
                    end
                    
                case 'StagesOn'
                    
                    ui_arr = ui_stages;
                    app.buttonControl( ui_arr, 'on');
                    
                    
                case 'FilesPrepared'
                    if app.htpPC.htpcfg.completed.FilenameEditField == 1 && app.htpPC.htpcfg.completed.TargetFolderEditField == 1
                        mode = app.htpPC.htpcfg.analysisMode;
                        switch mode
                            case 'Single'
                                ui_arr = ui_action;
                                checkfile = fullfile(app.htpPC.htpcfg.targetPath, app.htpPC.htpcfg.rawsubfolder, app.htpPC.htpcfg.rawfile );
                                %app.PrepareFilesButton.BackgroundColor = green;
                                app.PrepareFilesButton.Text = 'Recopy File';
                                %app.PrepareFilesButton.BackgroundColor = blue;
                                
                                app.LoadStagesButton.Enable = 1;
                                app.buttonControl( ui_arr, 'on');
                                app.htpPC.htpcfg.completed.PrepareStageButton = 1;
                                app.buttonHandler( 'prepare_button_clicked_once');
                                
                                
                                app.buttonControl( ui_output, 'on' );
                                
                            case 'Group'
                                
                                ui_arr = ui_action;
                                app.buttonControl( ui_arr, 'on');
                                app.PrepareFilesButton.BackgroundColor = green;
                                app.htpPC.htpcfg.completed.PrepareStageButton = 1;
                                app.buttonHandler( 'prepare_button_clicked_once');
                                app.LoadStagesButton.BackgroundColor = green;
                                app.LoadStagesButton.Enable = 1;
                                
                                app.buttonControl( ui_output, 'on' );
                                
                            case 'Turn_On_Comp_Tools'
                                
                                
                        end
                        
                    end
                    
                case 'prepare_button_clicked_once'
                    if app.htpPC.htpcfg.completed.PrepareStageButton == 1
                        
                        app.PrepareFilesButton.Text = 'Reload/Recopy';
                        app.PrepareFilesButton.BackgroundColor = blue;
                        app.LoadStagesButton.BackgroundColor = green;
                        
                    end
                    
                case 'lastLoaded'
                    mode = app.htpPC.htpcfg.analysisMode;
                    
                    switch mode
                        case 'Single'
                            ui_arr = [ui_fileAndFolder ui_resultFolder ui_action];
                            app.buttonControl( ui_arr, 'on');
                            app.PrepareFilesButton.BackgroundColor = green;
                            app.buttonControl( ui_output, 'on' );
                            
                        case 'Group'
                            ui_arr = [ui_fileAndFolder ui_action];
                            app.PrepareFilesButton.BackgroundColor = green;
                            
                            app.buttonControl( ui_arr, 'on');
                            app.buttonControl( ui_output, 'on' );
                            
                    end
                    
            end
        end
        
        
        function buttonControl( app, ui_arr, control )
            
            switch control
                case 'off'
                    toggle = 0;
                case 'on'
                    toggle = 1;
            end
            
            for i = 1 : length( ui_arr )
                
                if toggle == 1
                    ui_arr{i}.Enable = 1;
                else
                    ui_arr{i}.Enable = 0;
                end
                
            end
            
        end
        
        
        function buttonVisible( app, ui_arr, control )
            
            switch control
                case 'off'
                    toggle = 0;
                case 'on'
                    toggle = 1;
            end
            
            for i = 1 : length( ui_arr )
                
                if toggle == 1
                    ui_arr{i}.Visible = 1;
                else
                    ui_arr{i}.Visible = 0;
                end
                
            end
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.UIFigure.Name = 'High Throughput Pipeline v1.0';
                app.runInstance = 0;
                
                app.startupHandler('never_run');
                
                %    app.prepareAnalysisMenus;
                
            catch
                fprintf('\n\nStartup Error...\n\n');
                QuitMenuSelected(app);
                error('Closing htpInterface.');
                %app.delete;
                % delete(app.UIFigure);
                % app.htpPC.msgout('Error in Startup', 'step_error');
            end
            
            
        end

        % Button pushed function: OpenButton
        function OpenSingleFileButtonPushed(app, event)
            
            mode = app.htpPC.getAnalysisMode;
            
            switch mode
                
                case 'Single'
                    try
                        [file,path] = uigetfile(app.fileExtFilter);
                        figure(app.UIFigure);
                    catch
                        app.htpPC.msgout('Error: No file selected.', 'step_warning');
                        buttonHandler(app,'init');
                    end
                    
                    
                    if file ~= 0
                        app.FilenameEditField.Value = fullfile(path,file);
                        app.htpPC.setSingleRawFile( app.FilenameEditField.Value );
                        buttonHandler(app, 'resetPrepareButton');
                    else
                        app.htpPC.msgout('Error: No file selected.', 'step_warning');
                        buttonHandler(app,'init');
                    end
                    %updateRawFile(app);


                case 'Group'
                    try
                        folder = uigetdir(pwd,'Select Data Directory');
                        
                    catch
                        app.htpPC.msgout('Error: No directory selected.');
                    end
                    
                    app.FilenameEditField.Value = folder;
                    app.OutputFolderEditField.Value = folder;
                    
                    app.htpPC.setSingleRawFile( app.FilenameEditField.Value );
                    
                    %updateOutputDir(app);
                    figure(app.UIFigure);
                    buttonHandler(app, 'FilesPrepared');
                    
            end
            % load "raw" filename into app.FilenameEditField
            
            
        end

        % Value changed function: NetTypeDropDown
        function NetTypeDropDownValueChanged(app, event)
            
            value = app.NetTypeDropDown.Value;
            app.setNet(value);
            
        end

        % Button pushed function: PrepareFilesButton
        function PrepareFilesButtonPushed(app, event)
            
            try
                app.UserInitialsEditField.Enable = 1;
                app.StudyNameEditField.Enable = 1;
                
                app.UserInitialsEditField.Value = 'Default User';
                UserInitialsEditFieldValueChanged(app);
                app.StudyNameEditField.Value = 'Default Study Title';
                StudyNameEditFieldValueChanged(app);
                
                if app.htpPC.check_user == 0
                    app.htpPC.msgout('No username found. Please enter username before continuing.', 'step_error');
                end
                
                if app.htpPC.check_study_title == 0
                    app.htpPC.msgout('No study title found. Please enter study title before continuing.', 'step_error');
                end
                
                
                try
                    app.htpPC.start_EegLab_If_Needed;
                    app.htpPC.msgout(sprintf('Attempting to start EEGLAB...success. Path: %s', app.htpPC.htpcfg.eeglabpath));
                catch
                    app.htpPC.msgout(sprintf('Attempting to start EEGLAB...Failed. Path: %s', app.htpPC.htpcfg.eeglabpath));
                end
                
                
                mode = app.AnalysisModeSwitch.Value;
                
                switch mode
                    case 'Single'
                        
                        % copy original file to data directory
                        filename = app.FilenameEditField.Value;
                        [~, fileroot, ~] = fileparts(filename);
                        app.htpPC.msgout(sprintf('Single File Mode: Files Prepared.'));
                        
                        
                        % make RAW directory
                        app.htpPC.targetpath2basepath( app.StudyFolderEditField.Value, fileroot );
                        
                        % copy file
                        app.htpPC.copyRawfile2Basepath;
                        
                        app.OutputFolderEditField.Value = app.htpPC.htpcfg.basePath;
                        
                        
                        % switch button to reflect reset
                        % app.htpPC.htpcfg.completed.PrepareStageButton = 1;
                        %  disableFileOpen(app, 'on');
                        app.htpPC.import_filelist;
                        buttonHandler(app, 'FilesPrepared');
                        app.htpPC.msgout(sprintf('Single File Mode: Files Prepared.'));
                        app.htpPC.saveLastSettings;
                        
                        
                    case 'Group'
                        app.htpPC.import_filelist;
                        buttonHandler(app, 'FilesPrepared');
                        app.htpPC.msgout(sprintf('Group File Mode: Files Prepared.'));
                        app.OutputFolderEditField.Value = app.htpPC.htpcfg.basePath;
                        
                        app.htpPC.saveLastSettings;
                end
            catch
            end
            
            
        end

        % Button pushed function: SelectButton
        function SelectOutputDirButtonPushed(app, event)
            % get directory name for output files
            
            value = uigetdir;
            figure(app.UIFigure);
            
            app.htpPC.setTargetPath( value );
            
            %  app.htpPC.loadLastSettings;
            
            app.StudyFolderEditField.Value = value;
            
            %updateOutputDir(app);
            
            buttonHandler(app, 'resetPrepareButton');
            buttonHandler(app, 'FilesPrepared');
            
        end

        % Button pushed function: LoadinEEGLABButton
        function LoadinEEGLABButtonPushed(app, event)
            
            app.htpPC.sub = getSubject(app, app.stageselect);
            s = app.htpPC.sub(1);
            
            evalin('base', 'eeglab');
            s.loadDataset( app.stageselect );
            if strcmp( 'postcomps', app.stageselect )
                app.updateStatusText(sprintf('Removing components: %s', mat2str(s.proc_removeComps)));
                s.compRemove;
            end
            
            assignin('base', 'EEG', s.EEG);
            evalin('base', 'eeglab redraw');
            
        end

        % Callback function
        function RunStage3ButtonPushed(app, event)
            upt = @updateStatusText;
            
            app.getOptionValues;
            
            htp_stage3_fn(app, app.htpPC.htpcfg, upt);
        end

        % Callback function
        function RunStage4ButtonPushed(app, event)
            upt = @updateStatusText;
            
            app.getOptionValues;
            
            htp_stage4_fn(app, app.htpPC.htpcfg, upt);
        end

        % Button pushed function: Stage5Button
        function Stage5ButtonPushed(app, event)
            upt = @updateStatusText;
            
            app.getOptionValues;
            
            app.htpAM = htpAnalysisMaster;
            app.htpAM.inheritPreProcessMaster( app.htpPC );
            
            % sub = htp_stage5_fn2(app, app.htpPC.htpcfg, upt);
            %   app.htpPC.analysis_pow;
            %   app.htpPC.preprocess_stage5;
            
            
            app.htpAM.process_stage5;
            
            % app.htpPC.sub = sub;
            
            
        end

        % Callback function
        function ExploreDataDirectoryButtonPushed(app, event)
            try
                filename = app.FilenameEditField.Value;
                [~, filename, ~] = fileparts(filename);
                app.htpPC.openFolderInExplorer( filename );
            catch
            end
        end

        % Button pushed function: LoadStagesButton
        function LoadStagesButtonPushed(app, event)
            try
                % clean input
                mode = app.AnalysisModeSwitch.Value;
                
                if isempty( app.htpPC.sub )
                    app.htpPC.sub = app.getSubject( 'all' );
                    app.htpPC.check_stages( app.htpPC.sub );
                else
                    app.htpPC.check_stages( app.htpPC.sub );
                end
                
                items = app.htpPC.stage_info;
                
                idx = find(cell2mat(items(2,:)));
                stages = items(1,:);
                itemList = stages(idx);
                
                if isempty(itemList)
                    app.htpPC.msgout('No stages available', 'step_warning');
                    app.DropDown.Enable = 0;
                    app.LoadinEEGLABButton.Enable = 0;
                    
                else
                    app.DropDown.Enable = 1;
                    app.LoadinEEGLABButton.Enable = 1;
                    app.DropDown.Items = itemList;
                    app.stageselect = app.DropDown.Value;
                    %     app.loadSubTable( app.stageselect );
                end
                app.LoadStagesButton.BackgroundColor = [0.3 0.75 0.93];
                app.buttonHandler('StagesOn');
            catch
                app.htpPC.msgout('No stages available','step_warning');
                app.buttonHandler('StagesOn');
            end
            figure(app.UIFigure);
            % app.getOptionValues;
            
        end

        % Button pushed function: ViewFolderButton
        function ViewFolderButtonPushed(app, event)
            try
                app.htpPC.openFolderInExplorer( fullfile(app.OutputFolderEditField.Value) );
            catch
                errorMsg = lasterror;
                app.htpPC.msgout( sprintf('Error: %s', errorMsg.message ) );
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.stageselect = value;
            app.htpPC.msgout(sprintf('%s Stage selected.', value), 'step_complete');
            
        end

        % Button pushed function: pow_mtButton
        function pow_mtButtonPushed(app, event)
            upt = @updateStatusText;
            
            mode = app.htpPC.getAnalysisMode;
            
            switch mode
                
                case 'Single'
                    
                    app.getOptionValues;
                    assignin('base','sub', app.htpPC.sub);
                    evalin('base', 'powercalc(sub)')
                    
                    VarNames = app.htpPC.sub.powTable1.Properties.VariableNames;
                    labels = app.htpPC.sub.powTable1{:,1};
                    Data = app.htpPC.sub.powTable1{:,2:3};
                    str = sprintf('Power: %s\n', app.htpPC.sub.subj_basename);
                    str = strcat(str,sprintf('\n%s\t\t%s\t\t\t%s\n', VarNames{:}));
                    for i = 1 : length(labels)
                        str= strcat(str, sprintf('\n%2d-%2d\t\t%e\t%f\n', labels(i,1), labels(i,2), Data(i,1), Data(i,2)));
                        
                    end
                    evalin('base', 'EEG = sub.EEG');
                    
                    evalin('base', 'eeglab redraw');
                    
                    app.htpPC.msgout( str );
                    
                    app.powTable.ColumnName = VarNames;
                    app.powTable.Data = app.htpPC.sub.powTable1;
            end
            
            
            % ( htpcfg, sub, analysisType )
            % htp_stage5_fn(app.htpPC.htpcfg, app.htpPC.sub, 'pow_mt');
        end

        % Callback function
        function ComponentViewButtonPushed(app, event)
            
            %app.htpPC.sub = getSubject(app, app.stageselect);
            s = app.htpPC.sub(1);
            
            if strcmp( 'postcomps', app.stageselect )
                s.loadDataset( app.stageselect );
                
                if app.RemoveCheckBox == 1
                    
                    if app.EditCheckBox == 1
                        
                        app.updateStatusText(sprintf('REMOVING EDITED COMPONENTS: %s', mat2str(app.edit_removeComps)));
                        s.proc_removeComps = app.edit_removeComps;
                        s.compRemove;
                        
                    else
                        app.updateStatusText(sprintf('REMOVING ORIGINAL COMPONENTS: %s', mat2str(s.proc_removeComps)));
                        s.proc_removeComps = app.orig_removeComps;
                        s.compRemove;
                    end
                    
                else
                    app.updateStatusText(sprintf('Displaying Components: %s', mat2str(s.proc_removeComps)));
                end


                s.icview;
                pop_viewprops( s.EEG, 0 );
                app.updateStatusText(sprintf('Artifact Components: %s', mat2str(s.proc_removeComps)));
            end
            
            
        end

        % Callback function
        function EditCheckBoxValueChanged(app, event)
            value = app.EditCheckBox.Value;
            app.CompstoRemoveEditField.Enable = 1;
            
        end

        % Callback function
        function CompstoRemoveEditFieldValueChanged(app, event)
            
            value = app.CompstoRemoveEditField.Value;
            
            value = str2num(value);
            
            s = app.htpPC.sub(1);
            
            compsNo = length( s.EEG.reject.gcompreject );
            
            invalidComps = value > compsNo;
            
            if any(invalidComps)
                
                app.CompstoRemoveEditField.Value = mat2str(app.orig_removeComps);
                
                app.updateStatusText(sprintf('Entered Invalid Components: %s\nRestoring Original RemoveComps\nAvailable Components:\n %s', ...
                    mat2str(value(find(invalidComps))), mat2str(find(~s.EEG.reject.gcompreject) )));
                
            else
                
                app.edit_removeComps = value;
                
            end
            
            
        end

        % Callback function
        function ViewOriginalButtonPushed(app, event)
            
            s = app.htpPC.sub(1);
            
            if strcmp( 'postcomps', app.stageselect )
                
                s.loadDataset( app.stageselect );
                s.quickplot( app.stageselect, 1 );
                
            end
        end

        % Callback function
        function ViewRemovedButtonPushed(app, event)
            
            s = app.htpPC.sub(1);
            
            if strcmp( 'postcomps', app.stageselect )
                
                s.loadDataset( app.stageselect );
                
                if app.EditCheckBox.Value == 1
                    
                    app.updateStatusText(sprintf('REMOVING EDITED COMPONENTS: %s', mat2str(app.edit_removeComps)));
                    s.proc_removeComps = app.edit_removeComps;
                    %s.quickplot_compRemove;
                    
                else
                    app.updateStatusText(sprintf('REMOVING ORIGINAL COMPONENTS: %s', mat2str(s.proc_removeComps)));
                    s.proc_removeComps = app.orig_removeComps;
                    %s.quickplot_compRemove;
                end
                
                s.compRemove;
                app.updateStatusText(sprintf('Displaying Plot. Comps Removed: %s', mat2str(s.proc_removeComps)));
                pop_eegplot(s.EEG, 1, 1, 0);
                
                s.unloadDataset;
                
            end
        end

        % Button pushed function: LastButton
        function Last_SingleFileButton(app, event)
            
            app.startupHandler('init');
            
            app.buttonHandler('init');
            
            mode = app.AnalysisModeSwitch.Value;
            
            try
                switch mode
                    case 'Single'
                        
                        % app.loadLocalSettings; legacy
                        
                        app.htpPC.loadLastSettings;
                        app.NetTypeDropDown.Value = app.htpPC.htpcfg.chanNow.net_name;
                        app.setNet( app.NetTypeDropDown.Value  );
                        app.FilenameEditField.Value = app.htpPC.htpcfg.rawfile;
                        app.StudyFolderEditField.Value = app.htpPC.htpcfg.targetPath;
                        
                        
                        app.htpPC.setSingleRawFile( app.FilenameEditField.Value );
                        app.htpPC.setBasePath( app.StudyFolderEditField.Value );
                        
                        %app.htpPC.saveLastSettings;
                        
                        app.buttonHandler('lastLoaded');
                        % app.buttonHandler('FilesPrepared');
                        
                        
                        %updateOutputDir(app);
                        %updateRawFile(app);
                        
                    case 'Group'
                        app.htpPC.loadLastSettings;
                        app.NetTypeDropDown.Value = app.htpPC.htpcfg.localSettings.group.net;
                        app.setNet( app.NetTypeDropDown.Value  );
                        
                        
                        %updateRawFile(app);
                        
                        buttonHandler(app, 'groupMode');
                        
                        %app.htpPC.setSingleRawFile( app.FilenameEditField.Value );
                        app.htpPC.setBasePath( app.htpPC.htpcfg.localSettings.group.basePath );
                        app.FilenameEditField.Value = app.htpPC.htpcfg.localSettings.group.basePath;
                        
                        
                        app.buttonHandler('lastLoaded');
                end
                
            catch
                app.updateStatusText('ERROR: Local Saved Settings');
            end
        end

        % Value changed function: PresetDropDown
        function PresetDropDownValueChanged(app, event)
            value = app.PresetDropDown.Value;
            
            app.htpPC.msgout(sprintf('Preset Selected: %s\n', value), 'complete');
            
            if strcmp(value, 'No preset selected')
                app.setupOptions;
                app.htpPC.msgout( 'Stage Options reset to default.' , 'complete');
                
            else
                items = app.htpPC.xml_presets(:,1);
                itemIdx = find(strcmp(value, items));
                
                tempset = app.htpPC.xml_presets(itemIdx,:);
                
                startidx = find(strcmp('Begin Options', tempset)) + 1;
                
                masterset = fields(app.htpPC.htpcfg.opt);
                
                %  idx = zeros(1,length(tempset));
                idx = startidx;
                j = 1;
                for i = startidx : 2 : length( tempset )
                    
                    if ischar(tempset{i})
                        
                        resultarr{j,1} = tempset{i};
                        resultarr{j,2} = tempset{i+1};
                        j = j +1;
                    else
                    end
                    
                end
                
                master_change_idx = zeros(length(masterset),1);
                master_change_opt = cell(length(master_change_idx),3);
                
                for i = 1 : length(resultarr)
                    disp(resultarr(i,1));
                    tmpstr = split(masterset,'_');
                    tempidx = find(contains( tmpstr(:,2), resultarr{i,1}));
                    master_change_idx(tempidx) = true;
                    master_change_opt{tempidx, 1} = true;
                    master_change_opt{tempidx, 2} = resultarr{i,1};
                    master_change_opt{tempidx, 3} = resultarr{i,2};
                    tmpstr(tempidx,2)
                end
                
                optlabels = {app.OptionLabel(:).Text}';
                optlabels = split(optlabels,'_');
                app.OptionDropDown ( logical(master_change_idx) ).Value
                
                tempidx = find(master_change_idx);
                
                for i = 1 : length( tempidx )
                    
                    app.OptionDropDown ( tempidx(i) ).Value = master_change_opt{tempidx(i), 3};
                    app.htpPC.msgout(sprintf('%d. Option %s: %s\n',i, master_change_opt{tempidx(i), 2}, master_change_opt{tempidx(i), 3}), 'complete');
                    
                end
            end
            
        end

        % Callback function
        function ViewButtonPushed(app, event)
            t = app.powTable;
            
            powTable = app.htpPC.sub.powTable1;
            t.ColumnName = {'Band', 'Abs. Pow (mV2)', 'Rel. Pow'};
            t.Data = powTable(:,1:3);
            
            
        end

        % Callback function
        function SaveCSVButtonPushed(app, event)
            % save current UI table results into a CSV file
            % use only object properties \
            
            s = app.htpPC.sub(1);
            
            powTable = s.powTable1;
            
            saveFileName = fullfile(s.pathdb.analysis, [datestr(now,'yymmddHHMM') '_' s.subj_basename '_powTable.csv']);
            
            writetable(powTable, saveFileName);
            
            
        end

        % Button pushed function: LoadTriggersButton
        function LoadTriggersButtonPushed(app, event)
            
            % load all triggers in the file and load into radio boxes
            s = app.htpPC.sub(1);
            s.loadDataset('import'); % load stage 1 file prior to stage 2
            EEG = s.EEG;
            
            % get list of triggers
            uniqueEvents = unique({EEG.event.type});
            % count number of triggers
            noEvents = length(uniqueEvents);
            
            % temp storage of all current buttons in radio group
            bg = app.DefineTrigger1ButtonGroup;
            
            rbAll = bg.Children;
            
            
            for i = 1 : noEvents
                
                rb(i) = uiradiobutton( bg );
                
                rb(1).Text = uniqueEvents{i};
                
                
                fprintf('Button: %s\n', rb.Text);
                
            end
            
            bg.Children = rb;


        end

        % Menu selected function: EditStageOptionsXMLMenu
        function EditStageOptionsXMLMenuSelected(app, event)
            open(app.htpPC.xmlfile_options);
            
        end

        % Callback function
        function SetOptionsButtonPushed(app, event)
            app.SetOptionsButton.Enable = 'off';
            
            % Get szvalue and cvalue
            % ....
            szvalue = '';
            cvalue ='';
            
            % Call dialog box with input values
            app.DialogApp = optionsDialogApp(app,szvalue,cvalue);
            app.htpPC.htpcfg.optNow;
            app.htpPC.msgout('Assigned Preset Values.', 'step_complete');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % Delete both apps
            delete(app.DialogApp)
            delete(app)
            
        end

        % Value changed function: AnalysisModeSwitch
        function AnalysisModeSwitchValueChanged(app, event)
            value = app.AnalysisModeSwitch.Value;
            
            app.htpPC.setAnalysisMode( value );
            
            analysisModeSetup(app, value);
            
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            
            closereq;
            
        end

        % Menu selected function: OpenscriptfolderMenu
        function OpenscriptfolderMenuSelected(app, event)
            try
                app.htpPC.openFolderInExplorer( fullfile(app.htpPC.htpcfg.scriptPath) );
                app.htpPC.msgout( sprintf('Opening script directory: %s.', app.htpPC.htpcfg.scriptPath), 'step_complete' );
                
            catch
                errorMsg = lasterror;
                app.htpPC.msgout( sprintf('Error: %s', errorMsg.message ) );
            end
            
        end

        % Button pushed function: Stage1Button
        function Stage1ButtonPushed2(app, event)
            app.NetTypeDropDownValueChanged;
            app.getOptionValues;
            app.htpPC.htpcfg.autoprocflag = 0;
            app.htpPC.assignConfig2Subjects;
            app.htpPC.preprocess_stage1;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.getOptionValues;
            try
                
                app.loadSubTable( app.DropDown.Value );
                app.SaveButton.Enable = 0;
                figure(app.UIFigure);
                
            catch
                app.htpPC.msgout( 'Exited dialog without selection.');
                figure(app.UIFigure);
            end
            
        end

        % Button pushed function: Stage2Button
        function Stage2ButtonPushed2(app, event)
            app.getOptionValues;
            app.htpPC.htpcfg.autoprocflag = 0;
            %
            %             % assign proper epochs for events
            %             if strcmp(app.htpPC.htpcfg.optnow.Stage2_EpochType, 'Event')
            %                 % additional option selection
            %             app.dialog_EventEpochs = optionsDialogApp( app );
            %             end
            
            if strcmp('Yes', app.htpPC.htpcfg.optnow.Stage2_MergeFiles)
                
                app.htpPC.msgout('Merge mode active.');
                
            end
            
            app.htpPC.preprocess_stage2;
            
            % check if merge is successful
            
        end

        % Button pushed function: Stage3Button
        function Stage3ButtonPushed2(app, event)
            app.getOptionValues;
            app.htpPC.htpcfg.autoprocflag = 0;
            app.htpPC.preprocess_stage3;
        end

        % Button pushed function: Stage4Button
        function Stage4ButtonPushed2(app, event)
            app.getOptionValues;
            app.htpPC.htpcfg.autoprocflag = 0;
            app.htpPC.preprocess_stage4;
        end

        % Value changed function: UserInitialsEditField
        function UserInitialsEditFieldValueChanged(app, event)
            value = app.UserInitialsEditField.Value;
            app.htpPC.set_user(value);
        end

        % Value changed function: StudyNameEditField
        function StudyNameEditFieldValueChanged(app, event)
            value = app.StudyNameEditField.Value;
            app.htpPC.set_study_title(value);
        end

        % Cell selection callback: subTable
        function subTableCellSelection(app, event)
            
            idx = event.Indices(1);
            app.set_activeSelection( idx );
            dh = app.htpPC.detailHandler( idx , 'init');
            app.detailHandler( dh );
            
            if dh.postcomp == 1
                
                app.buttonHandler('Turn_On_Comp_Tools');
                
            end
            
        end

        % Button pushed function: EEGLABButton
        function EEGLABButtonPushed(app, event)
            
            if strcmp(app.htpPC.htpcfg.analysisMode, 'Single')
                
                evalin('base', 'eeglab');
                assignin('base', 'sub', app.htpPC.sub);
                assignin('base', 'htpcfg', app.htpPC.htpcfg);
                
            else
                
                idx = app.get_activeSelection;
                stage = app.stageselect;
                
                evalin('base', 'eeglab');
                
                app.htpPC.sub( idx ).loadDataset( stage );
                EEG = app.htpPC.sub( idx ).EEG;
                
                assignin('base', 'EEG', EEG);
                
                evalin('base', 'eeglab redraw');
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            idx = app.get_activeSelection;
            stage = app.stageselect;
            app.htpPC.sub( idx ).loadDataset( stage );
            
            app.htpPC.sub( idx ).fplot;
            
        end

        % Button pushed function: TFPlotButton
        function TFPlotButtonPushed(app, event)
            figure;
            idx = app.get_activeSelection;
            stage = app.stageselect;
            %evalin('base', 'eeglab');
            app.htpPC.sub( idx ).loadDataset( stage );
            app.htpPC.sub( idx ).compRemove;
            
            EEG = app.htpPC.sub( idx ).EEG;
            % assignin('base', 'EEG', EEG);
            % evalin('base', 'eeglab redraw');
            app.htpPC.sub( idx ).fplot
            
            pop_newtimef( EEG, 1, 1, [-500  796], [3         0.5] , 'topovec', 1, 'elocs', EEG.chanlocs, 'chaninfo', EEG.chaninfo, 'caption', 'E1', 'baseline',[0], 'plotphase', 'off', 'padratio', 1, 'winsize', 125);
            
        end

        % Callback function
        function MergeButtonPushed(app, event)
            app.MergeButton.Enable = 0;
            app.DialogApp = mergeDialogApp(app);
            
        end

        % Button pushed function: SaveasListedPresetButton
        function SaveasListedPresetButtonPushed(app, event)
            try
                
                
                datetime.setDefaultFormats('default','MMddyy');
                
                prompt = {'Enter Preset Display Name:'};
                dlgtitle = 'Save Current Settings as Preset:';
                
                dims = [1 35];
                definput = {'Default'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                titlestr = char(answer);
                code = [titlestr(regexp(titlestr,'\w+')) '_' char(datetime)];
                
            catch
                
            end
            
            app.getOptionValues;
            titlestr = titlestr;
            code = code;
            net = app.htpPC.htpcfg.chanNow.net_name;
            
            f = split(fields(app.htpPC.htpcfg.optnow),'_');
            full = fields(app.htpPC.htpcfg.optnow);
            fprintf('\n********* CUT AND PASTE INTO %s TO ADD PRESET ***********\n\n', app.htpPC.xmlfile_presets);
            fprintf('\n<Study>\n\t<Title>%s</Title>\n\t<Code>%s</Code>\n\t<Net>%s</Net>\n\t\t<Settings>\n', titlestr, code, net);
            
            for i = 1 : length( f )
                tmpvalue = char(app.htpPC.htpcfg.optnow.(full{i}));
                fprintf('\t\t\t<%s>%s</%s>\n', f{i,2}, tmpvalue ,f{i,2});
                
            end
            fprintf('\t\t</Settings>\n</Study>\n\n');
            fprintf('\n********* CUT AND PASTE INTO %s TO ADD PRESET ***********\n\n', app.htpPC.xmlfile_presets);
            
            
        end

        % Menu selected function: ViewdatamethodsMenu
        function ViewdatamethodsMenuSelected(app, event)
            
            methodsview(eegDataClass);
            
        end

        % Menu selected function: ViewpreprocessingmethodsMenu
        function ViewpreprocessingmethodsMenuSelected(app, event)
            
            methodsview(htpPreprocessMaster);
            
        end

        % Menu selected function: EditPresetOptionsXMLMenu
        function EditPresetOptionsXMLMenuSelected(app, event)
            
            open(app.htpPC.xmlfile_presets);
            
        end

        % Menu selected function: EditElectrodeOptionsXMLMenu
        function EditElectrodeOptionsXMLMenuSelected(app, event)
            open(app.htpPC.xmlfile_elec);
        end

        % Selection changed function: AnalysisTree
        function AnalysisTreeSelectionChanged(app, event)
            selectedNodes = app.AnalysisTree.SelectedNodes;
            display(selectedNodes);
            app.ActivateNodeButton.Enable = 1;


        end

        % Button pushed function: ActivateNodeButton
        function ActivateNodeButtonPushed(app, event)
            
            disp('Start Analysis');
            app.getOptionValues;
            
            app.htpAM.process_stage5;
            %app.htpPC.preprocess_stage5('tf_power_logscale');
            
            
        end

        % Menu selected function: AboutHTPMenu
        function AboutHTPMenuSelected(app, event)
            msgbox(sprintf('High Throuhgput Pipeline\nObject Oriented Code\nEEGLAB\n\Fieldtrip\nVersion 1.0'));
        end

        % Menu selected function: CSVfileoutputMenu
        function CSVfileoutputMenuSelected(app, event)
            matlab.desktop.editor.openAndGoToFunction([ app.htpPC.htpcfg.scriptPath '\classes\eegDataClass.m'], 'outputRow');
        end

        % Button pushed function: ResetInterfaceButton
        function ResetInterfaceButtonPushed(app, event)
            app.startupFcn;
        end

        % Menu selected function: ExportsubjectstoworkspaceMenu
        function ExportsubjectstoworkspaceMenuSelected(app, event)
            if length(app.htpPC.sub) >= 1
                app.getOptionValues;
                app.update_sub_htpcfg;
                assignin('base', 'sub', app.htpPC.sub);
                app.htpPC.msgout(sprintf('%d subjects exported to base workspace in variable ''sub''.', length(app.htpPC.sub)));
            else
                app.htpPC.msgout('No loaded subjects available to export.');
                
            end
        end

        % Value changed function: SDetailsListBox
        function SDetailsListBoxValueChanged(app, event)
            searchstr = app.SDetailsListBox.Value;
            
            app.SOutputTextArea.Value = app.htpPC.detailHandler( app.activeSelection, 'get', searchstr );
            
        end

        % Button pushed function: AnalysisModeButton
        function AnalysisModeButtonPushed(app, event)
            app.htpAM = htpAnalysisMaster;
            app.htpAM.inheritPreProcessMaster( app.htpPC );
            app.htpAM.setupVariables;
            
            upt = @updateStatusText;
            
            app.getOptionValues;
            
            
        end

        % Cell edit callback: subTable
        function subTableCellEdit(app, event)
            app.SaveButton.Enable = 1;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            modified_table = app.subTable.Data;
            column_names = app.subTable.ColumnName;
            modified_table = cell2table(modified_table);
            modified_table.Properties.VariableNames = column_names;
            
            app.htpPC.dataTable = modified_table;
            
            app.htpPC.csvDataTable( app.DropDown.Value, 'save');
            app.SaveButton.Enable = 0;
            
        end

        % Button pushed function: Save2WorkspaceButton
        function Save2WorkspaceButtonPushed(app, event)
            % save key variables to be directly accessible to matlab workspace
            
            assignin('base', 'sub', app.htpPC.sub);
            assignin('base', 'htpPC', app.htpPC);


        end

        % Button pushed function: ReFilterButton
        function ReFilterButtonPushed(app, event)
            
            % Refiltering process for ICA
            
            % 1. create new folder containing raw files and Stage 2 spreadsheet + MAT file that contains preprocessing info.
            % 2. designate the new folder with a suffix '_hpass2' or a description that reflects the stage1 changes
            % 3. Change settings and run stage 1 with the new settings
            % 4. When complete, restart the pipeline and assign the new folder
            % 5. Now, instead of stage 2, click "Re-Filter" button
            % 6. Select the previous Stage 2 spreadsheet with the preprocessing chagnes
            % 7. Select the correct stage 1 spreadsheet (if you have several in the folder)
            % 8. Following this script, there will be a new stage 2 spreadsheet and can run stage 3
            
            app.getOptionValues;
            
            %getSubject(app, 'preica');
            stage = 'preica';
            app.htpPC.getStageCSV( stage, app.htpPC.htpcfg.basePath);

            app.htpPC.csvDataTable( stage, 'load');
            
            %
            %             % assign proper epochs for events
            %             if strcmp(app.htpPC.htpcfg.optnow.Stage2_EpochType, 'Event')
            %                 % additional option selection
            %             app.dialog_EventEpochs = optionsDialogApp( app );
            %             end
            try
                for i = 1 : length( app.htpPC.sub ) 
                    
                    % qi check
                    if ~isempty(app.htpPC.sub(i).proc_xmax_post)
                        
                        app.htpPC.sub(i).updatePaths(app.htpPC.basePath);
                        app.htpPC.htpcfg.autoproc(i).basename       = app.htpPC.sub(i).subj_basename;
                        app.htpPC.htpcfg.autoproc(i).tmprej_chans   = csv2tmprej_chans( app.htpPC.sub(i).proc_badchans );
                        app.htpPC.htpcfg.autoproc(i).tmprej_cont    = csv2tmprej_cont( app.htpPC.sub(i).proc_removed_regions );
                        app.htpPC.htpcfg.autoproc(i).tmprej_epochs  = csv2tmprej_epochs( app.htpPC.sub(i).epoch_badid );
                        app.htpPC.htpcfg.autoproc(i).orig_srate  = app.htpPC.sub(1).proc_sRate1;
                    else
                        str = sprintf('Warning: %s does not appear to contain valid EEG data and is ignored for refiltering.', app.htpPC.sub(i).subj_basename);
                        app.htpPC.msgout( str );
                        
                    end
                end
                app.htpPC.htpcfg.autoprocflag = 1;
                
            catch
                
                app.htpPC.msgout('Unable to match previous processing with refilter. Must manually repeat process.');
                
            end
            
            if strcmp('Yes', app.htpPC.htpcfg.optnow.Stage2_MergeFiles)
                
                app.htpPC.msgout('Merge mode active.');
                
            end
            
            app.htpPC.preprocess_stage2_auto;
            
            % check if merge is successful
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.AboutHTPMenu = uimenu(app.FileMenu);
            app.AboutHTPMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutHTPMenuSelected, true);
            app.AboutHTPMenu.Text = 'About HTP';
            app.UtilityMenu = uimenu(app.UIFigure);
            app.UtilityMenu.Text = 'Utility';
            app.EditStageOptionsXMLMenu = uimenu(app.UtilityMenu);
            app.EditStageOptionsXMLMenu.MenuSelectedFcn = createCallbackFcn(app, @EditStageOptionsXMLMenuSelected, true);
            app.EditStageOptionsXMLMenu.Text = 'Edit Stage Options XML';
            app.EditPresetOptionsXMLMenu = uimenu(app.UtilityMenu);
            app.EditPresetOptionsXMLMenu.MenuSelectedFcn = createCallbackFcn(app, @EditPresetOptionsXMLMenuSelected, true);
            app.EditPresetOptionsXMLMenu.Text = 'Edit Preset Options XML';
            app.EditElectrodeOptionsXMLMenu = uimenu(app.UtilityMenu);
            app.EditElectrodeOptionsXMLMenu.MenuSelectedFcn = createCallbackFcn(app, @EditElectrodeOptionsXMLMenuSelected, true);
            app.EditElectrodeOptionsXMLMenu.Text = 'Edit Electrode Options XML';
            app.OpenscriptfolderMenu = uimenu(app.UtilityMenu);
            app.OpenscriptfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenscriptfolderMenuSelected, true);
            app.OpenscriptfolderMenu.Text = 'Open script folder';
            app.ViewdatamethodsMenu = uimenu(app.UtilityMenu);
            app.ViewdatamethodsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewdatamethodsMenuSelected, true);
            app.ViewdatamethodsMenu.Text = 'View data methods';
            app.ViewpreprocessingmethodsMenu = uimenu(app.UtilityMenu);
            app.ViewpreprocessingmethodsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewpreprocessingmethodsMenuSelected, true);
            app.ViewpreprocessingmethodsMenu.Text = 'View preprocessing methods';
            app.ExportsubjectstoworkspaceMenu = uimenu(app.UtilityMenu);
            app.ExportsubjectstoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportsubjectstoworkspaceMenuSelected, true);
            app.ExportsubjectstoworkspaceMenu.Text = 'Export subjects to workspace';
            app.CodeMenu = uimenu(app.UIFigure);
            app.CodeMenu.Text = 'Code';
            app.ReportingMenu = uimenu(app.CodeMenu);
            app.ReportingMenu.Text = 'Reporting';
            app.CSVfileoutputMenu = uimenu(app.ReportingMenu);
            app.CSVfileoutputMenu.MenuSelectedFcn = createCallbackFcn(app, @CSVfileoutputMenuSelected, true);
            app.CSVfileoutputMenu.Text = 'CSV file output';
            app.EEGHighThroughputPipelineHTPLabel = uilabel(app.UIFigure);
            app.EEGHighThroughputPipelineHTPLabel.Text = 'EEG High Throughput Pipeline (HTP)';
            app.ProgramOutputLabel = uilabel(app.UIFigure);
            app.ProgramOutputLabel.Text = 'Program Output:';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.FIlenameEditFieldLabel = uilabel(app.UIFigure);
            app.FIlenameEditFieldLabel.Text = 'FIlename:';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.NetTypeDropDownLabel = uilabel(app.UIFigure);
            app.NetTypeDropDownLabel.Text = 'Net Type:';
            app.NetTypeDropDown = uidropdown(app.UIFigure);
            app.NetTypeDropDown.Items = {'Please select EEG net type', 'EGI128(*.raw)', 'BrainVision (*.eeg)', 'EGI64 (*.raw)', 'Option 4'};
            app.NetTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NetTypeDropDownValueChanged, true);
            app.NetTypeDropDown.Value = 'Please select EEG net type';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSingleFileButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step 1:';
            app.StudyFolderEditFieldLabel = uilabel(app.UIFigure);
            app.StudyFolderEditFieldLabel.Text = 'Study Folder:';
            app.StudyFolderEditField = uieditfield(app.UIFigure, 'text');
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputDirButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.LoadinEEGLABButton = uibutton(app.UIFigure, 'push');
            app.LoadinEEGLABButton.ButtonPushedFcn = createCallbackFcn(app, @LoadinEEGLABButtonPushed, true);
            app.LoadinEEGLABButton.Text = 'Load in EEGLAB';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = '';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'No Stages'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'No Stages';
            app.LoadStagesButton = uibutton(app.UIFigure, 'push');
            app.LoadStagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStagesButtonPushed, true);
            app.LoadStagesButton.Text = 'Load Stages';
            app.OutputFolderEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder:';
            app.OutputFolderEditField = uieditfield(app.UIFigure, 'text');
            app.ViewFolderButton = uibutton(app.UIFigure, 'push');
            app.ViewFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFolderButtonPushed, true);
            app.ViewFolderButton.Text = 'View Folder';
            app.SetupPresetsandFileLocationsLabel = uilabel(app.UIFigure);
            app.SetupPresetsandFileLocationsLabel.Text = 'Setup Presets and File Locations';
            app.AnalysisModeSwitchLabel = uilabel(app.UIFigure);
            app.AnalysisModeSwitchLabel.Text = 'Analysis Mode:';
            app.AnalysisModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.AnalysisModeSwitch.Items = {'Single', 'Group'};
            app.AnalysisModeSwitch.ValueChangedFcn = createCallbackFcn(app, @AnalysisModeSwitchValueChanged, true);
            app.AnalysisModeSwitch.Value = 'Single';
            app.LastButton = uibutton(app.UIFigure, 'push');
            app.LastButton.ButtonPushedFcn = createCallbackFcn(app, @Last_SingleFileButton, true);
            app.LastButton.Text = 'Last';
            app.PresetDropDownLabel = uilabel(app.UIFigure);
            app.PresetDropDownLabel.Text = 'Preset:';
            app.PresetDropDown = uidropdown(app.UIFigure);
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetDropDownValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'PreProcessing';
            app.Stage1Button = uibutton(app.PreProcessingTab, 'push');
            app.Stage1Button.ButtonPushedFcn = createCallbackFcn(app, @Stage1ButtonPushed2, true);
            app.Stage1Button.Text = 'Stage 1';
            app.Stage2Button = uibutton(app.PreProcessingTab, 'push');
            app.Stage2Button.ButtonPushedFcn = createCallbackFcn(app, @Stage2ButtonPushed2, true);
            app.Stage2Button.Text = 'Stage 2';
            app.Stage3Button = uibutton(app.PreProcessingTab, 'push');
            app.Stage3Button.ButtonPushedFcn = createCallbackFcn(app, @Stage3ButtonPushed2, true);
            app.Stage3Button.Text = 'Stage 3';
            app.Stage4Button = uibutton(app.PreProcessingTab, 'push');
            app.Stage4Button.ButtonPushedFcn = createCallbackFcn(app, @Stage4ButtonPushed2, true);
            app.Stage4Button.Text = 'Stage 4';
            app.Stage5Button = uibutton(app.PreProcessingTab, 'push');
            app.Stage5Button.ButtonPushedFcn = createCallbackFcn(app, @Stage5ButtonPushed, true);
            app.Stage5Button.Text = 'Stage 5';
            app.SaveasListedPresetButton = uibutton(app.PreProcessingTab, 'push');
            app.SaveasListedPresetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasListedPresetButtonPushed, true);
            app.SaveasListedPresetButton.Text = 'Save as Listed Preset';
            app.AnalysisModuleTab = uitab(app.TabGroup);
            app.AnalysisModuleTab.Title = 'Analysis Module';
            app.LoadTriggersButton = uibutton(app.AnalysisModuleTab, 'push');
            app.LoadTriggersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTriggersButtonPushed, true);
            app.LoadTriggersButton.Text = 'Load Triggers';
            app.SelectEpochEventDropDownLabel = uilabel(app.AnalysisModuleTab);
            app.SelectEpochEventDropDownLabel.Text = 'Select Epoch Event:';
            app.SelectEpochEventDropDown = uidropdown(app.AnalysisModuleTab);
            app.PowerBandLabel = uilabel(app.AnalysisModuleTab);
            app.PowerBandLabel.Text = 'Power Band:';
            app.PowerBandDropDown = uidropdown(app.AnalysisModuleTab);
            app.PowerBandTable = uitable(app.AnalysisModuleTab);
            app.PowerBandTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PowerBandTable.RowName = {};
            app.pow_mtButton = uibutton(app.UIFigure, 'push');
            app.pow_mtButton.ButtonPushedFcn = createCallbackFcn(app, @pow_mtButtonPushed, true);
            app.pow_mtButton.Text = 'pow_mt';
            app.powTable = uitable(app.UIFigure);
            app.powTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.powTable.RowName = {};
            app.DatasetInfoLabel = uilabel(app.UIFigure);
            app.DatasetInfoLabel.Text = 'Dataset Info:';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ResultDropDownLabel = uilabel(app.UIFigure);
            app.ResultDropDownLabel.Text = 'Result:';
            app.ResultDropDown = uidropdown(app.UIFigure);
            app.ComponentPanelpostcompssetsonly = uipanel(app.UIFigure);
            app.ComponentPanelpostcompssetsonly.Title = 'Component Panel (postcomps sets only)';
            app.ComponentViewButton = uibutton(app.ComponentPanelpostcompssetsonly, 'push');
            app.ComponentViewButton.Text = 'Component View';
            app.RemoveCheckBox = uicheckbox(app.ComponentPanelpostcompssetsonly);
            app.RemoveCheckBox.Text = 'Remove';
            app.EditCheckBox = uicheckbox(app.ComponentPanelpostcompssetsonly);
            app.EditCheckBox.Text = 'Edit';
            app.CompstoRemoveEditFieldLabel = uilabel(app.ComponentPanelpostcompssetsonly);
            app.CompstoRemoveEditFieldLabel.Text = 'Comps to Remove:';
            app.CompstoRemoveEditField = uieditfield(app.ComponentPanelpostcompssetsonly, 'text');
            app.ViewOriginalButton = uibutton(app.ComponentPanelpostcompssetsonly, 'push');
            app.ViewOriginalButton.Text = 'View Original';
            app.ViewRemovedButton = uibutton(app.ComponentPanelpostcompssetsonly, 'push');
            app.ViewRemovedButton.Text = 'View Removed';
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.Text = 'View';
            app.SaveCSVButton = uibutton(app.UIFigure, 'push');
            app.SaveCSVButton.Text = 'Save CSV';
            app.UserInitialsEditFieldLabel = uilabel(app.UIFigure);
            app.UserInitialsEditFieldLabel.Text = 'User Initials:';
            app.UserInitialsEditField = uieditfield(app.UIFigure, 'text');
            app.UserInitialsEditField.ValueChangedFcn = createCallbackFcn(app, @UserInitialsEditFieldValueChanged, true);
            app.StudyNameEditFieldLabel = uilabel(app.UIFigure);
            app.StudyNameEditFieldLabel.Text = 'Study Name:';
            app.StudyNameEditField = uieditfield(app.UIFigure, 'text');
            app.StudyNameEditField.ValueChangedFcn = createCallbackFcn(app, @StudyNameEditFieldValueChanged, true);
            app.PrepareFilesButton = uibutton(app.UIFigure, 'push');
            app.PrepareFilesButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareFilesButtonPushed, true);
            app.PrepareFilesButton.Text = 'Prepare Files';
            app.subTable = uitable(app.UIFigure);
            app.subTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.subTable.RowName = {};
            app.subTable.CellEditCallback = createCallbackFcn(app, @subTableCellEdit, true);
            app.subTable.CellSelectionCallback = createCallbackFcn(app, @subTableCellSelection, true);
            app.SubjectDetailPanel = uipanel(app.UIFigure);
            app.SubjectDetailPanel.Title = 'Subject Detail';
            app.basenameLabel = uilabel(app.SubjectDetailPanel);
            app.basenameLabel.Text = 'basename';
            app.SDetailsListBoxLabel = uilabel(app.SubjectDetailPanel);
            app.SDetailsListBoxLabel.Text = 'S.Details:';
            app.SDetailsListBox = uilistbox(app.SubjectDetailPanel);
            app.SDetailsListBox.Items = {};
            app.SDetailsListBox.ValueChangedFcn = createCallbackFcn(app, @SDetailsListBoxValueChanged, true);
            app.SDetailsListBox.Value = {};
            app.SOutputTextAreaLabel = uilabel(app.SubjectDetailPanel);
            app.SOutputTextAreaLabel.Text = 'S.Output:';
            app.SOutputTextArea = uitextarea(app.SubjectDetailPanel);
            app.AnalysisModeButton = uibutton(app.UIFigure, 'push');
            app.AnalysisModeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisModeButtonPushed, true);
            app.AnalysisModeButton.Text = 'Analysis Mode';
            app.AnalysisTree = uitree(app.UIFigure);
            app.AnalysisTree.SelectionChangedFcn = createCallbackFcn(app, @AnalysisTreeSelectionChanged, true);
            app.TimeFrequencyNode = uitreenode(app.AnalysisTree);
            app.TimeFrequencyNode.Text = 'Time Frequency';
            app.LogScaleNode = uitreenode(app.TimeFrequencyNode);
            app.LogScaleNode.Text = 'Log Scale';
            app.LinearScaleNode = uitreenode(app.TimeFrequencyNode);
            app.LinearScaleNode.Text = 'Linear Scale';
            app.Node4 = uitreenode(app.TimeFrequencyNode);
            app.Node4.Text = 'Node4';
            app.ActivateNodeButton = uibutton(app.UIFigure, 'push');
            app.ActivateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateNodeButtonPushed, true);
            app.ActivateNodeButton.Text = 'Activate Node';
            app.ResetInterfaceButton = uibutton(app.UIFigure, 'push');
            app.ResetInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @ResetInterfaceButtonPushed, true);
            app.ResetInterfaceButton.Text = 'Reset Interface';
            app.EEGLABButton = uibutton(app.UIFigure, 'push');
            app.EEGLABButton.ButtonPushedFcn = createCallbackFcn(app, @EEGLABButtonPushed, true);
            app.EEGLABButton.Text = 'EEGLAB';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TFPlotButton = uibutton(app.UIFigure, 'push');
            app.TFPlotButton.ButtonPushedFcn = createCallbackFcn(app, @TFPlotButtonPushed, true);
            app.TFPlotButton.Text = 'TF Plot';
            app.AnalysisModuleLabel = uilabel(app.UIFigure);
            app.AnalysisModuleLabel.Text = 'Analysis Module:';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Save2WorkspaceButton = uibutton(app.UIFigure, 'push');
            app.Save2WorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @Save2WorkspaceButtonPushed, true);
            app.Save2WorkspaceButton.Text = 'Save2Workspace';
            app.ReFilterButton = uibutton(app.UIFigure, 'push');
            app.ReFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ReFilterButtonPushed, true);
            app.ReFilterButton.Text = 'Re-Filter ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = htpInterface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end