classdef manualReviewApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        manualReviewAppUIFigure      matlab.ui.Figure
        htpPreprocessing2020v1ManualReviewLabel  matlab.ui.control.Label
        centerfig                    matlab.ui.control.UIAxes
        RecordingsListBoxLabel       matlab.ui.control.Label
        RecordingsListBox            matlab.ui.control.ListBox
        ApprovedListBoxLabel         matlab.ui.control.Label
        ApprovedListBox              matlab.ui.control.ListBox
        SaveandContinueButton        matlab.ui.control.Button
        ExcludeListBoxLabel          matlab.ui.control.Label
        ExcludeListBox               matlab.ui.control.ListBox
        SelectNewComponentsButton    matlab.ui.control.Button
        CompsOnlyListBoxLabel        matlab.ui.control.Label
        CompsOnlyListBox             matlab.ui.control.ListBox
        ReProcessSelectedDataButton  matlab.ui.control.Button
        ReProcessListBoxLabel        matlab.ui.control.Label
        ReProcessListBox             matlab.ui.control.ListBox
        ReviewedEditFieldLabel       matlab.ui.control.Label
        ReviewedEditField            matlab.ui.control.NumericEditField
        SetManualReviewStatusButtonGroup  matlab.ui.container.ButtonGroup
        ReprocessButton              matlab.ui.control.ToggleButton
        CompsOnlyButton              matlab.ui.control.ToggleButton
        ExcludeButton                matlab.ui.control.ToggleButton
        ApproveButton                matlab.ui.control.ToggleButton
        reprocessLabel               matlab.ui.control.Label
        componlyLabel                matlab.ui.control.Label
        excludeLabel                 matlab.ui.control.Label
        approvedLabel                matlab.ui.control.Label
        Label2                       matlab.ui.control.Label
        ReviewedButton               matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        am % htpPreprocessing Class
        s % active subject
        currentSel % current selection of recording
        callingApp % calling app
        reprocessArr;
        compsonlyArr;
        excludeArr; % Description
        approveArr; %
    end
    
    methods (Access = private)
        
        function results = updateCenterImage(app, action)
            
            s = app.am.sub(app.currentSel);
            
            switch action
                case 'spect'
                    axis image
                    spectimgfile = app.am.create_filename_spectfig( s );
                    spectimg = imread(fullfile(s.pathdb.figs, spectimgfile));
                    spectimg_size = size(spectimg) .* .8;
                    ax = app.centerfig;
                    
                    title(ax, []);
                    xlabel(ax, []);
                    ylabel(ax, []);
                    ax.XAxis.TickLabels = {};
                    ax.YAxis.TickLabels = {};
                    % Display image and stretch to fill axes
                    I = imagesc(spectimg, 'Parent', ax, ...
                        'XData', [1 ax.Position(3)], ...
                        'YData', [1 ax.Position(4)]);
                    % Set limits of axes
                    ax.XLim = [0 I.XData(2)];
                    ax.YLim = [0 I.YData(2)];
                    %zoom(4);
                otherwise
            end
            
        end
        
        function buttonHandler(app, action)
            
            switch action
                
                case 'firstrun'
                    
                otherwise
                    uialert(app.manualReviewApp, 'button Handler', 'check action Handler');
                    
            end
            
        end
        
        function actionHandler(app, action)
            
            switch action
                
                case 'setupRecordings'
                    
                    for i = 1 : length( app.am.sub )
                        items{i} = app.getReviewedRecordingName( i );
                    end
                    
                    app.RecordingsListBox.Items = items;
                    app.RecordingsListBox.ItemsData =  compose('%d', [1:length(items)]);
                    
                    app.currentSel = 1;
                    
                    app.updateCenterImage('spect');
                    
                    app.am.updateMraStatusCounts;
                    
                    app.updatePanels;
                    
                    app.updateExcludeStatus( app.currentSel );
                    
                    app.RecordingsListBox.Value = '1';
                    
                    % app.updateReviewedStatus( );
                    
                    app.changeReviewedStatus(   app.am.sub(app.currentSel).mra.reviewed, app.currentSel );
                    
                    
                otherwise
                    uialert(app.manualReviewApp, 'action Handler', 'check action Handler');
                    
            end
            
        end
        
        function loadActiveSubject(app, index)
            
            app.currentSel = index;
            
            app.updateCenterImage('spect');
            
            app.updateExcludeStatus( index );
            
            app.changeReviewedStatus(   app.am.sub(app.currentSel).mra.reviewed, app.currentSel );
            
            app.updatePanels;
            
            
        end
        
        function updateExcludeStatus( app, index )
            
            s = app.am.sub( index );
            
            val = s.exclude_status;
            
            activatebutton = true(1,4);
            activatebutton(val) = true;
            
            ui_arr = app.SetManualReviewStatusButtonGroup.Buttons;
            
            for i= 1 : length(ui_arr)
                
                ui_arr(i).Value = activatebutton(i);
                
            end
            
        end
        
        function str = getReviewedRecordingName( app, index )
            s = app.am.sub( index );
            
            action = s.mra.reviewed;
            
            if action
                str = strcat(s.subj_basename, '(', num2str(index), ') ');
            else
                str = strcat( s.subj_basename, '(', num2str(index), ')*');
            end
            
        end
        
        function changeReviewedStatus(app, action, index)
            
            % get the index
            app.currentSel = index;
            
            % change the review status
            app.am.sub( app.currentSel ).mra.reviewed = action;
            
            % get new string name
            str = app.getReviewedRecordingName( index );
            
            % action = true, reviewed (no *)
            % action = false, new (*)
            app.RecordingsListBox.Items{ app.currentSel } = str;
            
            % visual display
            app.ReviewedEditField.Value =  app.getTotalReviewed;
            
            if action
                app.ReviewedButton.BackgroundColor = [0 1 0];
            else
                app.ReviewedButton.BackgroundColor = [1 0 0];
            end
            app.am.updateMraStatusCounts;
            app.updatePanels;
            
        end
        
        function res = getTotalReviewed( app )
            for i = 1 : length(app.am.sub)
                review_arr(i) = app.am.sub(i).mra.reviewed;
            end
            res = sum(double(review_arr));
        end
        
        function res = updatePanels(app)
            ui_arr = { app.ReProcessListBox, app.CompsOnlyListBox, ...
                app.ExcludeListBox, app.ApprovedListBox};
            ui_arr2 = { app.reprocessLabel, app.componlyLabel, ...
                app.excludeLabel, app.approvedLabel };
            
            status = app.am.htpcfg.mra.status;
            names = app.am.htpcfg.mra.status_names;
            total_n = length({app.am.sub.exclude_status});
            for i = 1 : length( ui_arr )
                
                ui_arr{i}.Items = names{i};
                ui_arr{i}.ItemsData = status{i};
                ui_arr2{i}.Text = sprintf('%d of %d', length( status{i} ), total_n);
            end
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, am)
            app.callingApp = callingApp;
            app.am = am;
            
            app.buttonHandler('firstrun');
            
            app.actionHandler('setupRecordings');


        end

        % Value changed function: RecordingsListBox
        function RecordingsListBoxValueChanged(app, event)
            value = str2num(app.RecordingsListBox.Value);
            app.loadActiveSubject(value);
        end

        % Value changed function: ReviewedButton
        function ReviewedButtonValueChanged2(app, event)
            value = app.ReviewedButton.Value;
            index = str2num(app.RecordingsListBox.Value);
            app.changeReviewedStatus( value, index );
            
        end

        % Close request function: manualReviewAppUIFigure
        function manualReviewAppUIFigureCloseRequest(app, event)
            app.callingApp.ManualReviewButton.Enable = 1;
            % app.callingApp.am = app.am;
            delete(app)
            
        end

        % Selection changed function: SetManualReviewStatusButtonGroup
        function SetManualReviewStatusButtonGroupSelectionChanged(app, event)
            value = str2num(app.RecordingsListBox.Value);
            
            app.RecordingsListBox
            selectedButton = app.SetManualReviewStatusButtonGroup.SelectedObject;
            
            switch SelectedButton.Text
                case 'Exclude'
                    app.am.sub(value).exclude_subject('yes');
                case 'Approve'
                    app.am.sub(value).exclude_subject('no');
                case 'Comps Only'
                    app.am.sub(value).exclude_subject('no');
                case 'Reprocess'
                    app.am.sub(value).exclude_subject('no');
            end
        
        app.am.sub( app.currentSel ).setMraStatus(selectedButton.Text);
        
        app.am.updateMraStatusCounts;
        
        app.updatePanels;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.manualReviewAppUIFigure = uifigure;
            app.manualReviewAppUIFigure.Name = 'manualReviewApp';
            app.manualReviewAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @manualReviewAppUIFigureCloseRequest, true);
            app.htpPreprocessing2020v1ManualReviewLabel = uilabel(app.manualReviewAppUIFigure);
            app.htpPreprocessing2020v1ManualReviewLabel.Text = 'htpPreprocessing 2020v1: Manual Review';
            app.centerfig = uiaxes(app.manualReviewAppUIFigure);
            title(app.centerfig, 'Title')
            xlabel(app.centerfig, 'X')
            ylabel(app.centerfig, 'Y')
            app.RecordingsListBoxLabel = uilabel(app.manualReviewAppUIFigure);
            app.RecordingsListBoxLabel.Text = 'Recordings';
            app.RecordingsListBox = uilistbox(app.manualReviewAppUIFigure);
            app.RecordingsListBox.Items = {};
            app.RecordingsListBox.ValueChangedFcn = createCallbackFcn(app, @RecordingsListBoxValueChanged, true);
            app.RecordingsListBox.Value = {};
            app.ApprovedListBoxLabel = uilabel(app.manualReviewAppUIFigure);
            app.ApprovedListBoxLabel.Text = 'Approved:';
            app.ApprovedListBox = uilistbox(app.manualReviewAppUIFigure);
            app.ApprovedListBox.Items = {};
            app.ApprovedListBox.Value = {};
            app.SaveandContinueButton = uibutton(app.manualReviewAppUIFigure, 'push');
            app.SaveandContinueButton.Text = 'Save and Continue';
            app.ExcludeListBoxLabel = uilabel(app.manualReviewAppUIFigure);
            app.ExcludeListBoxLabel.Text = 'Exclude:';
            app.ExcludeListBox = uilistbox(app.manualReviewAppUIFigure);
            app.ExcludeListBox.Items = {};
            app.ExcludeListBox.Value = {};
            app.SelectNewComponentsButton = uibutton(app.manualReviewAppUIFigure, 'push');
            app.SelectNewComponentsButton.Text = 'Select New Components';
            app.CompsOnlyListBoxLabel = uilabel(app.manualReviewAppUIFigure);
            app.CompsOnlyListBoxLabel.Text = 'Comps Only:';
            app.CompsOnlyListBox = uilistbox(app.manualReviewAppUIFigure);
            app.CompsOnlyListBox.Items = {};
            app.CompsOnlyListBox.Value = {};
            app.ReProcessSelectedDataButton = uibutton(app.manualReviewAppUIFigure, 'push');
            app.ReProcessSelectedDataButton.Text = 'Re-Process Selected Data';
            app.ReProcessListBoxLabel = uilabel(app.manualReviewAppUIFigure);
            app.ReProcessListBoxLabel.Text = 'Re-Process:';
            app.ReProcessListBox = uilistbox(app.manualReviewAppUIFigure);
            app.ReProcessListBox.Items = {};
            app.ReProcessListBox.Value = {};
            app.ReviewedEditFieldLabel = uilabel(app.manualReviewAppUIFigure);
            app.ReviewedEditFieldLabel.Text = 'Reviewed:';
            app.ReviewedEditField = uieditfield(app.manualReviewAppUIFigure, 'numeric');
            app.SetManualReviewStatusButtonGroup = uibuttongroup(app.manualReviewAppUIFigure);
            app.SetManualReviewStatusButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SetManualReviewStatusButtonGroupSelectionChanged, true);
            app.SetManualReviewStatusButtonGroup.Title = 'Set Manual Review Status:';
            app.ReprocessButton = uitogglebutton(app.SetManualReviewStatusButtonGroup);
            app.ReprocessButton.Text = 'Reprocess';
            app.ReprocessButton.Value = true;
            app.CompsOnlyButton = uitogglebutton(app.SetManualReviewStatusButtonGroup);
            app.CompsOnlyButton.Text = 'Comps Only';
            app.ExcludeButton = uitogglebutton(app.SetManualReviewStatusButtonGroup);
            app.ExcludeButton.Text = 'Exclude';
            app.ApproveButton = uitogglebutton(app.SetManualReviewStatusButtonGroup);
            app.ApproveButton.Text = 'Approve';
            app.reprocessLabel = uilabel(app.manualReviewAppUIFigure);
            app.componlyLabel = uilabel(app.manualReviewAppUIFigure);
            app.excludeLabel = uilabel(app.manualReviewAppUIFigure);
            app.approvedLabel = uilabel(app.manualReviewAppUIFigure);
            app.ReviewedButton = uibutton(app.manualReviewAppUIFigure, 'state');
            app.ReviewedButton.ValueChangedFcn = createCallbackFcn(app, @ReviewedButtonValueChanged2, true);
            app.ReviewedButton.Text = 'Reviewed';
            app.Label2 = uilabel(app.manualReviewAppUIFigure);
            app.Label2.Text = 'Label2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = manualReviewApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.manualReviewAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.manualReviewAppUIFigure)
        end
    end
end